classdef Game < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        RESTARTButton  matlab.ui.control.Button
        STARTButton    matlab.ui.control.Button
        Label_2        matlab.ui.control.Label
        Label          matlab.ui.control.Label
        Button_9       matlab.ui.control.Button
        Button_8       matlab.ui.control.Button
        Button_7       matlab.ui.control.Button
        Button_6       matlab.ui.control.Button
        Button_5       matlab.ui.control.Button
        Button_4       matlab.ui.control.Button
        Button_3       matlab.ui.control.Button
        Button_2       matlab.ui.control.Button
        Button         matlab.ui.control.Button
    end

    
    properties (Access = public)
        t=timer;
        gamestarted=0;% Description
    end
    
    methods (Access = public)
        
        function stopt(app)
            app.gamestarted=0;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            %t=timer;
            app.t.Period=1;
            app.t.ExecutionMode="fixedRate";
            app.t.TasksToExecute=60;
            app.t.TimerFcn=@(~,thisEvent)ty(app);
            app.t.Stopfcn=@(~,thisEvent)stopt(app);
            app.gamestarted=1;
            start(app.t);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if(app.gamestarted==1)
                app.Label.Text=num2str(str2double(app.Label.Text)+50);
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            if(app.gamestarted==1)
                app.Label.Text=num2str(str2double(app.Label.Text)+50);
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            if(app.gamestarted==1)
                app.Label.Text=num2str(str2double(app.Label.Text)+50);
            end
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            if(app.gamestarted==1)
                app.Label.Text=num2str(str2double(app.Label.Text)+50);
            end
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            if(app.gamestarted==1)
                app.Label.Text=num2str(str2double(app.Label.Text)+50);
            end
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            if(app.gamestarted==1)
                app.Label.Text=num2str(str2double(app.Label.Text)+50);
            end
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            if(app.gamestarted==1)
                app.Label.Text=num2str(str2double(app.Label.Text)+50);
            end
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            if(app.gamestarted==1)
                app.Label.Text=num2str(str2double(app.Label.Text)+50);
            end
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            if(app.gamestarted==1)
                app.Label.Text=num2str(str2double(app.Label.Text)+50);
            end
        end

        % Button pushed function: RESTARTButton
        function RESTARTButtonPushed(app, event)
            stop(app.t);
            app.Label.Text=num2str(0);
            app.Label_2.Text=num2str(60);
            app.t=timer;
            STARTButtonPushed(app, event);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'press.jpg');
            app.Button.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = fullfile(pathToMLAPP, 'press.jpg');
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = fullfile(pathToMLAPP, 'press.jpg');
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = fullfile(pathToMLAPP, 'press.jpg');
            app.Button_4.Text = '';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Icon = fullfile(pathToMLAPP, 'press.jpg');
            app.Button_5.Text = '';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Icon = fullfile(pathToMLAPP, 'press.jpg');
            app.Button_6.Text = '';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Icon = fullfile(pathToMLAPP, 'press.jpg');
            app.Button_7.Text = '';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Icon = fullfile(pathToMLAPP, 'press.jpg');
            app.Button_8.Text = '';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Icon = fullfile(pathToMLAPP, 'press.jpg');
            app.Button_9.Text = '';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '0';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '60';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.RESTARTButton = uibutton(app.UIFigure, 'push');
            app.RESTARTButton.ButtonPushedFcn = createCallbackFcn(app, @RESTARTButtonPushed, true);
            app.RESTARTButton.Text = 'RESTART';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Game

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end